classdef NFLPlayersApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        YearSlider               matlab.ui.control.Slider
        AnimationStateButton     matlab.ui.control.StateButton
        LabelDropDown            matlab.ui.control.Label
        ColorMapDropDown         matlab.ui.control.DropDown
        ColorMapFlippedCheckBox  matlab.ui.control.CheckBox
        TextArea                 matlab.ui.control.TextArea
    end

    properties(Constant)
        MarkerSize = 500;
    end

    properties (Access = private)
        DataModel;
        AnimationTimer
        Scatter
        UpdateSliderValueInRedraw = true; % Whether next redraw should include updating YearSlider value
    end

    properties (Access = public, SetObservable)
        ColorMapName = 'parula';
        ColorMapLength = 10;
        ColorMapFlipped = false;       
        ColorSaturatationPercent = 0.04; % less than 1% of bins fall above this.
        AnimationPeriod = 0.25;                
    end

    properties (Access = private, SetObservable)
        ColorMap;
    end

    methods (Access = private)      
        function startAnimation(app)
            app.AnimationStateButton.Text = 'Stop';
            start(app.AnimationTimer);
        end

        function stopAnimation(app)
            app.AnimationStateButton.Text = 'Play';
            stop(app.AnimationTimer);
        end
                
        function redraw(app)
            if app.UpdateSliderValueInRedraw
                app.YearSlider.Value = app.DataModel.CurrentYear;
            else
                app.UpdateSliderValueInRedraw = true;
            end
            app.UIAxes.Title.String = sprintf('%d', app.DataModel.CurrentYear);
            redrawAxes(app);                        
        end

        function redrawAxes(app)
            thisYearsData = app.DataModel.CurrentYearData;
            set(app.Scatter, ...
                'XData', thisYearsData.Weight, ...
                'YData', thisYearsData.Height, ...
                'CData', convertPercentsToMarkerColor(app, thisYearsData.Percent));
        end
        
        function markerColors = convertPercentsToMarkerColor(app, percents)    
            colorMap = app.ColorMap;
            colorMapLength = app.ColorMapLength;
            percentages = linspace(0, app.ColorSaturatationPercent, colorMapLength);
            indices = linspace(1, colorMapLength, colorMapLength);
            colormapIndx = interp1(percentages, indices, percents, 'nearest', colorMapLength);
            markerColors = colorMap(colormapIndx, :);                        
        end

        function colorMap = calculateColorMap(app)
            colorMap = feval(app.ColorMapName, app.ColorMapLength);
            if app.ColorMapFlipped
                colorMap = flipud(colorMap);
            end 
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ColorMap = calculateColorMap(app);
            app.Scatter = scatter(app.UIAxes, [], [], NFLPlayersApp.MarkerSize, [], ...
                'filled', 'square', 'MarkerEdgeColor', [0.6 0.6 0.6]);
            app.AnimationTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', app.AnimationPeriod, ...
                'TimerFcn', @(~,~)nextYear(app.DataModel));

            app.DataModel = NFLPlayersModel();
            addlistener(app.DataModel, 'CurrentYear', 'PostSet', @(~,~)redraw(app));
            addlistener(app,           'ColorMap',    'PostSet', @(~,~)redraw(app));                      
            redraw(app);           
        end

        % Value changed function: AnimationStateButton
        function toggleAnimation(app, event)
            value = app.AnimationStateButton.Value;
            if value                
                startAnimation(app);
            else
                stopAnimation(app);
            end

        end

        % Close request function: UIFigure
        function closeApp(app, event)
            stopAnimation(app);
            delete(app.AnimationTimer);
            delete(app.UIFigure);
        end

        % Value changing function: YearSlider
        function setYearFromSliderChanging(app, event)
            if app.AnimationTimer.Running
                app.AnimationStateButton.Value = false;
                stopAnimation(app);
            end
            app.UpdateSliderValueInRedraw = false;
            app.DataModel.CurrentYear = round(event.Value);

        end

        % Value changed function: ColorMapDropDown
        function colormapChanged(app, event)
            app.ColorMapName = app.ColorMapDropDown.Value;
            try 
                app.ColorMap = calculateColorMap(app);
                if event.Edited
                    app.ColorMapDropDown.Items{end+1} = app.ColorMapName;
                end
            catch
                app.ColorMapDropDown.Value = event.PreviousValue;
            end
        end

        % Value changed function: ColorMapFlippedCheckBox
        function colorMapFlippedChanged(app, event)
            app.ColorMapFlipped = app.ColorMapFlippedCheckBox.Value;     
            app.ColorMap = calculateColorMap(app);        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'NFL players: height & weight over time';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeApp, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Weight (lbs)')
            ylabel(app.UIAxes, 'Height')
            app.UIAxes.XLim = [140 350];
            app.UIAxes.YLim = [65 80];
            app.UIAxes.YTickLabel = {'5''6'''''; '5''8'''''; '5''10'''''; '6''0'''''; '6''2'''''; '6''4'''''; '6''6'''''};
            app.YearSlider = uislider(app.UIFigure);
            app.YearSlider.Limits = [1920 2014];
            app.YearSlider.MajorTicks = [1920 1925 1930 1935 1940 1945 1950 1955 1960 1965 1970 1975 1980 1985 1990 1995 2000 2005 2010 2014];
            app.YearSlider.ValueChangingFcn = createCallbackFcn(app, @setYearFromSliderChanging, true);
            app.YearSlider.MinorTicks = [];
            app.YearSlider.Value = 1920;
            app.AnimationStateButton = uibutton(app.UIFigure, 'state');
            app.AnimationStateButton.ValueChangedFcn = createCallbackFcn(app, @toggleAnimation, true);
            app.AnimationStateButton.Text = 'Play';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Color Map:';
            app.ColorMapDropDown = uidropdown(app.UIFigure);
            app.ColorMapDropDown.Items = {'parula', 'summer', 'winter', 'hot'};
            app.ColorMapDropDown.Editable = 'on';
            app.ColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapChanged, true);
            app.ColorMapDropDown.Value = 'parula';
            app.ColorMapFlippedCheckBox = uicheckbox(app.UIFigure);
            app.ColorMapFlippedCheckBox.ValueChangedFcn = createCallbackFcn(app, @colorMapFlippedChanged, true);
            app.ColorMapFlippedCheckBox.Text = 'Invert';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Enable = 'off';
            app.TextArea.Value = {'Based on: http://noahveltman.com/nflplayers/'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NFLPlayersApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end